classdef lab3 < matlab.apps.AppBase
    %LAB3 Week 4
    %   Isaac Bergl
    %   22710992
    %   
    %   func: LoadImageButtonPushed
    %      Opens file explorer and loads selected image into memory and 
    %      dsiplays it.
    %   
    %      input: 
    %         app (application state variable)
    %         event (event state variable)
    %   
    %   func: HistogramEqualizeButtonPushed
    %      Applies a histogram equalization algorithm to the input image 
    %      and displays it to the screen.
    %   
    %      input: 
    %         app (application state variable)
    %         event (event state variable)
    %      
    %   func: HighBoostButtonPushed
    %      Applies a high boost filter using the numerical parameter in 
    %      the box next to the button to the input image and displays 
    %      it to the screen.
    %   
    %      input: 
    %         app (application state variable)
    %         event (event state variable)
    %   
    %   func: LowPassButtonPushed
    %      Applies a low pass filter using the two numerical inputs to 
    %      the right of the button to the input image and displays it to 
    %      the screen
    %   
    %      input: 
    %         app (application state variable)
    %         event (event state variable)
    %   
    %   func: HighPassButtonPushed
    %      Applies a high pass filter to the input image and displays 
    %      it to the screen
    %   
    %      input: 
    %         app (application state variable)
    %         event (event state variable)
    %   
    %   
    %   
    %   

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        lowpassInputB            matlab.ui.control.NumericEditField
        lowpassInputA            matlab.ui.control.NumericEditField
        highboostInput           matlab.ui.control.NumericEditField
        HighPassButton           matlab.ui.control.Button
        LowPassButton            matlab.ui.control.Button
        HighBoostButton          matlab.ui.control.Button
        HistogramEqualizeButton  matlab.ui.control.Button
        LoadImageButton          matlab.ui.control.Button
        UIAxes_output            matlab.ui.control.UIAxes
        UIAxes_input             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % input image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
           % open file explorer to select the filename
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           % load image into application
           inputRGB = imread(fullname);
           % make image greyscale
           inputGREY = rgb2gray(inputRGB);
           % set as application state variable
           app.image = inputGREY;

           % display filename and image to GUI
           app.UIAxes_input.Title.String = filename;
           imshow(inputGREY, 'Parent', app.UIAxes_input);  
        end

        % Button pushed function: HistogramEqualizeButton
        function HistogramEqualizeButtonPushed(app, event)
            % apply histogram equalization to image
            histEqualized = histeq(app.image);

            % display title and image to GUI
            app.UIAxes_output.Title.String = 'Histogram Equalized';
            imshow(histEqualized, 'Parent', app.UIAxes_output);
        end

        % Button pushed function: HighBoostButton
        function HighBoostButtonPushed(app, event)
            % load numerical input into application
            boost = app.highboostInput.Value;
            
            % create high boost filter using boost parameter
            laplacianKernel = [-1, -1, -1; -1, 0, -1; -1, -1, -1];
            deltaFunction = [0, 0, 0; 0, 1, 0; 0, 0, 0];
            kernel = laplacianKernel + (9*boost-1) * deltaFunction;
            kernel = kernel / 9;
            
            % apply filter
            highBoosted = imfilter(app.image, kernel, 'same');

            % display title and image to GUI
            app.UIAxes_output.Title.String = 'High Boost';
            imshow(highBoosted, 'Parent', app.UIAxes_output);
           
        end

        % Button pushed function: LowPassButton
        function LowPassButtonPushed(app, event)
            % load numerical inputs into application
            filtSize = app.lowpassInputA.Value;
            sigma = app.lowpassInputB.Value;
            
            % create guassian filter
            guassfilt = imgaussfilt(app.image, sigma, "FilterSize", filtSize);

            % display title and image to GUI
            app.UIAxes_output.Title.String = 'Low Pass';
            imshow(guassfilt, 'Parent', app.UIAxes_output);

        end

        % Button pushed function: HighPassButton
        function HighPassButtonPushed(app, event)
            % create preset gaussian filter
            filter = fspecial('Gaussian', 5, 3 );

            % subtract the gaussian filtered image from the original image
            highpassed = app.image - imfilter(app.image, filter, 'same');

            % display title and image to GUI
            app.UIAxes_output.Title.String = 'High Pass';
            imshow(highpassed, 'Parent', app.UIAxes_output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_input = uiaxes(app.UIFigure);
            app.UIAxes_input.Visible = 'off';
            app.UIAxes_output = uiaxes(app.UIFigure);
            app.UIAxes_output.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.HistogramEqualizeButton = uibutton(app.UIFigure, 'push');
            app.HistogramEqualizeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizeButtonPushed, true);
            app.HistogramEqualizeButton.Text = 'Histogram Equalize';
            app.HighBoostButton = uibutton(app.UIFigure, 'push');
            app.HighBoostButton.ButtonPushedFcn = createCallbackFcn(app, @HighBoostButtonPushed, true);
            app.HighBoostButton.Text = 'High Boost';
            app.LowPassButton = uibutton(app.UIFigure, 'push');
            app.LowPassButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassButtonPushed, true);
            app.LowPassButton.Text = 'Low Pass';
            app.HighPassButton = uibutton(app.UIFigure, 'push');
            app.HighPassButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassButtonPushed, true);
            app.HighPassButton.Text = 'High Pass';
            app.highboostInput = uieditfield(app.UIFigure, 'numeric');
            app.highboostInput.Value = 1.5;
            app.lowpassInputA = uieditfield(app.UIFigure, 'numeric');
            app.lowpassInputA.Value = 3;
            app.lowpassInputB = uieditfield(app.UIFigure, 'numeric');
            app.lowpassInputB.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end