classdef week5lab < matlab.apps.AppBase
    %WEEK5LAB IsaacBergl_22710992_Lab04
    %   a) Canny and Sobel edge detection techniques are being used
    %   b) Edge detection threshold determines how sensitive the
    %   transformation is to detecting edges - a lower threshold will be
    %   more likely to accept a potential edge and an edge whereas a higher
    %   threshold will be more likely to ignore it. Hence a higher
    %   threshold results in a more sparse image and a lower threshold will
    %   have a high density of detected lines
    %   c) The circle is detected by considering every discrete position
    %   and radius of an arbitary circles on the line image. The circle
    %   that intersects with the most points is most likely to be a circle
    %   in the actual image, so that set of position and radius values will
    %   be returned by the find circles function

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DetectCirclesButton             matlab.ui.control.Button
        DetectEdgesButton               matlab.ui.control.Button
        RadiusSlider                    matlab.ui.control.Slider
        RadiusSizeDetectionSliderLabel  matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdforEdgeDetectionSliderLabel  matlab.ui.control.Label
        Title                           matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        LoadImageButton                 matlab.ui.control.Button
        InputDisplay                    matlab.ui.control.UIAxes
        OutputDisplay                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % Description
        circlesVisible = false % to show the circles or not
        edgeImage % hough transform of image
        algorithm % string of the algorithm used for hough edge detection
        threshold % threshold for hough edge detection
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImage(app, event)
           % open file explorer to select the filename
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           % load image into application
           inputRGB = imread(fullname);
           % make image greyscale
           % set as application state variable
           app.image = inputRGB;
           
           %update parameters
           UpdateAlgorithm(app, event);
           UpdateThreshold(app, event);

           % display filename and image to GUI
           app.InputDisplay.Title.String = "Input Image";
           imshow(inputRGB, 'Parent', app.InputDisplay);  
           
        end

        % Value changed function: RadiusSlider, ThresholdSlider
        function DetectCircles(app, event)
            % update edge image
            UpdateEdges(app,event)
            % get radius
            radius = round(app.RadiusSlider.Value);
            
            % find circles
            [centers, radii] = imfindcircles(app.edgeImage, [radius-4, radius+4],'ObjectPolarity', 'bright','Sensitivity', 0.93);
            % clear input and output displays
            cla(app.OutputDisplay);
            cla(app.InputDisplay);
            % print title and show images
            app.OutputDisplay.Title.String = "Hough Transform Image";
            imshow(app.edgeImage, 'Parent', app.OutputDisplay);
            imshow(app.image, 'Parent', app.InputDisplay);  
            
            % if we found any circles
            if not(isempty(centers))    
                hold on;
                % show circles on both figures
                viscircles(app.OutputDisplay, centers, radii,'EdgeColor','b');
                figure("Visible",false);
                set(gca,'visible','off');
                viscircles(app.InputDisplay, centers, radii,'EdgeColor','b');
                disp(radii);
                hold off;
            end
            
        end

        % Button pushed function: DetectEdgesButton
        function UpdateEdges(app, event)
            % change default threshold values based on algorithm selected
            
           if app.algorithm == "canny"
               app.threshold = 0.39;
               app.ThresholdSlider.Value = 0.39;
           elseif app.algorithm == "sobel"
               app.threshold = 0.08;
               app.ThresholdSlider.Value = 0.08;
           end
            
           % do edge detection transform
            app.edgeImage = edge(rgb2gray(app.image), app.algorithm, app.threshold);
           % output to display
            app.OutputDisplay.Title.String = "Hough Transform Image";
            imshow(app.edgeImage, 'Parent', app.OutputDisplay);
        end

        % Button pushed function: DetectCirclesButton
        function ToggleCircles(app, event)
            % toggle circles visible condition
            app.circlesVisible = not(app.circlesVisible);
            % calculate the detected circles
            DetectCircles(app,event)
        end

        % Value changed function: AlgorithmDropDown
        function UpdateAlgorithm(app, event)
           % update algorithm
            app.algorithm = app.AlgorithmDropDown.Value;
            % calculate the detected cirlces
           DetectCircles(app, event);
        end

        % Value changing function: ThresholdSlider
        function UpdateThreshold(app, event)
            % update threshold value
            app.threshold = app.ThresholdSlider.Value;
            % calculate the detected cirlces
            DetectCircles(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OutputDisplay = uiaxes(app.UIFigure);
            title(app.OutputDisplay, 'Title')
            zlabel(app.OutputDisplay, {''; ''})
            app.OutputDisplay.Visible = 'off';
            app.InputDisplay = uiaxes(app.UIFigure);
            title(app.InputDisplay, 'Title')
            xlabel(app.InputDisplay, {''; ''})
            app.InputDisplay.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImage, true);
            app.LoadImageButton.Text = 'Load Image';
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'canny', 'sobel', ''};
            app.AlgorithmDropDown.ItemsData = {'canny', 'sobel', '', ''};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateAlgorithm, true);
            app.AlgorithmDropDown.Value = 'canny';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Finding Circles in Images with Hough Transform';
            app.ThresholdforEdgeDetectionSliderLabel = uilabel(app.UIFigure);
            app.ThresholdforEdgeDetectionSliderLabel.Text = 'Threshold for Edge Detection';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0.001 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @DetectCircles, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @UpdateThreshold, true);
            app.ThresholdSlider.Value = 0.3;
            app.RadiusSizeDetectionSliderLabel = uilabel(app.UIFigure);
            app.RadiusSizeDetectionSliderLabel.Text = 'Radius Size Detection';
            app.RadiusSlider = uislider(app.UIFigure);
            app.RadiusSlider.Limits = [2 100];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @DetectCircles, true);
            app.RadiusSlider.Value = 22;
            app.DetectEdgesButton = uibutton(app.UIFigure, 'push');
            app.DetectEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEdges, true);
            app.DetectEdgesButton.Text = {'Detect Edges'; ''};
            app.DetectCirclesButton = uibutton(app.UIFigure, 'push');
            app.DetectCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleCircles, true);
            app.DetectCirclesButton.Text = 'Detect Circles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = week5lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end