classdef IsaacBergl_22710992_Lab04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SegmentWhitePeppersButton   matlab.ui.control.Button
        SegmentYellowPeppersButton  matlab.ui.control.Button
        SegmentRedPeppersButton     matlab.ui.control.Button
        SegmentSkinButton           matlab.ui.control.Button
        SegmentGreenPeppersButton   matlab.ui.control.Button
        SegmentIrisButton           matlab.ui.control.Button
        HueRangeLabel               matlab.ui.control.Label
        UpperThresholdSlider        matlab.ui.control.Slider
        UpperThresholdSliderLabel   matlab.ui.control.Label
        LowerThresholdSlider        matlab.ui.control.Slider
        LowerThresholdSliderLabel   matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        Title                       matlab.ui.control.Label
        ColourDisplay               matlab.ui.control.UIAxes
        OutputImage                 matlab.ui.control.UIAxes
        InputImage                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % Holds the input image as HSV
        lowerThreshold % decimal of lower threshold value
        upperThreshold % decimal of upper threshold value

        satUpper % upper limit on saturation
        satLower % lower limit on saturation

        brightUpper % upper limit on brightness
        brightLower % lower limit on brightness
    end
    
    methods (Access = private)

        % called to update the output image display
        function results = updateImage(app)
            % get size of image
            [w, h, c] = size(app.image);
            % create black and white array defaulted to black
            out = zeros(w,h);

            % is lower bound less than upper bound
            lowerLessThanUpper = (app.lowerThreshold < app.upperThreshold);

            % for every pixel (r,c)
            for r = 1:size(app.image, 1)   
                for c = 1:size(app.image, 2) 
                    % hue value of pixel
                    hue =  app.image(r, c, 1);

                    % saturation value of pixel
                    sat = app.image(r, c, 2);

                    % if we are limiting saturation for skin detection
                    if  sat < app.satLower | sat > app.satUpper
                        continue
                    end
                    

                    % get area inbetween if lower < upper
                    if lowerLessThanUpper & ((hue>=app.lowerThreshold) & (hue<=app.upperThreshold))
                       out(r,c) = 1;
                    end

                    % get area around if lower > upper
                    if not(lowerLessThanUpper) & ((hue>=app.lowerThreshold) | (hue<=app.upperThreshold))
                         out(r,c) = 1;
                    end
                   
                end
            end

            %display segmented image
            imshow(out, 'Parent', app.OutputImage); 
        
        end
        
        % called to update the colour scale 
        function results = updateColour(app)
            
            % set low and high to the min and max values
            low = min(app.lowerThreshold, app.upperThreshold);
            high = max(app.lowerThreshold, app.upperThreshold);
            
            % if lower > upper make bound above the lower bound
            if (app.lowerThreshold > app.upperThreshold)
                low = low + 1;
            end
            disp(app.lowerThreshold)
            disp(app.upperThreshold)
            % create a scale from low to high 
            N = 500;
            col = ones(1, N, 3);
            % wrap scale on interval of [0 1]
            col(1, :, 1) = mod(linspace(low, high, N), 1);
            % extend scale down to make an image
            col = repelem(col, 100, 1, 1);

            %display image
            imshow(hsv2rgb(col), 'Parent', app.ColourDisplay);
        end
        
        % resets saturation and brightness bounds
        function results = resetSaturationBrightness(app)
            % reset saturation
            app.satLower = 0.03;
            app.satUpper = 1;
            
            % reste brightness
            app.brightLower = 0;
            app.brightUpper = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % read image
            app.image = rgb2hsv(imread('peppers.png'));

            % show image
            imshow(hsv2rgb(app.image), 'Parent', app.InputImage); 
            
            % update thresholds
            app.lowerThreshold = app.LowerThresholdSlider.Value;
            app.upperThreshold = app.UpperThresholdSlider.Value;


            % update colour map and output image
            updateColour(app);
            updateImage(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
           % open file explorer to select the filename
           [filename,filepath] = uigetfile({'*.*'}, 'Select File to Open');
           fullname = [filepath, filename];
           % load image into application
           inputRGB = imread(fullname);

           % store as application variable as a HSV image
           app.image = rgb2hsv(inputRGB);  

           % diaply image
           imshow(hsv2rgb(app.image), 'Parent', app.InputImage); 
            
           % reset saturation and brightness bounds
           resetSaturationBrightness(app)

           % update output image
           updateImage(app);
        end

        % Value changed function: LowerThresholdSlider
        function LowerThresholdSliderValueChanged(app, event)
            % set lower threshold app variable 
            value = app.LowerThresholdSlider.Value;
            app.lowerThreshold = value;

           % reset saturation and brightness bounds
           resetSaturationBrightness(app)

            % update colour map and output image
            updateColour(app);
            updateImage(app);
        end

        % Value changed function: UpperThresholdSlider
        function UpperThresholdSliderValueChanged(app, event)
            % set lower threshold app variable 
            value = app.UpperThresholdSlider.Value;
            app.upperThreshold = value;

           % reset saturation and brightness bounds
           resetSaturationBrightness(app)
            
            % update colour map and output image
            updateColour(app);
            updateImage(app);
        end

        % Button pushed function: SegmentIrisButton
        function SegmentIrisButtonPushed(app, event)
            % set lower and upper threshold
            app.lowerThreshold = 0.44;
            app.upperThreshold = 0.64;
            app.LowerThresholdSlider.Value = 0.44;
            app.UpperThresholdSlider.Value = 0.64;

           % reset saturation and brightness bounds
           resetSaturationBrightness(app)

            %update colour map and output image
            updateColour(app);
            updateImage(app);
        end

        % Button pushed function: SegmentGreenPeppersButton
        function SegmentGreenPeppersButtonPushed(app, event)
            % set lower and upper threshold
            app.lowerThreshold = 0.15;
            app.upperThreshold = 0.44;
            app.LowerThresholdSlider.Value = 0.15;
            app.UpperThresholdSlider.Value = 0.44;

           % reset saturation and brightness bounds
           resetSaturationBrightness(app)

            %update colour map and output image
            updateColour(app);
            updateImage(app);
        end

        % Button pushed function: SegmentSkinButton
        function SegmentSkinButtonPushed(app, event)
            % set hue thresholds
            app.lowerThreshold = 0.042;
            app.upperThreshold = 0.138;
            app.LowerThresholdSlider.Value = 0.042;
            app.UpperThresholdSlider.Value = 0.138;

           % reset saturation and brightness bounds
           resetSaturationBrightness(app)
            
            % set limits on saturation
            app.satLower = 0.04;
            app.satUpper = 0.38;

            %update colour map and output image
            updateColour(app);
            updateImage(app);
        end

        % Button pushed function: SegmentRedPeppersButton
        function SegmentRedPeppersButtonPushed(app, event)
            % set lower and upper threshold
            app.lowerThreshold = 0.96;
            app.upperThreshold = 0.04;
            app.LowerThresholdSlider.Value = 0.96;
            app.UpperThresholdSlider.Value = 0.04;
           % reset saturation and brightness bounds
           resetSaturationBrightness(app)

            %update colour map and output image
            updateColour(app);
            updateImage(app);
        end

        % Button pushed function: SegmentYellowPeppersButton
        function SegmentYellowPeppersButtonPushed(app, event)
            % set lower and upper threshold
            app.lowerThreshold = 0.1026;
            app.upperThreshold = 0.1428;
            app.LowerThresholdSlider.Value = 0.1026;
            app.UpperThresholdSlider.Value = 0.1428;

           % reset saturation and brightness bounds
           resetSaturationBrightness(app)

            %update colour map and output image
            updateColour(app);
            updateImage(app);
        end

        % Button pushed function: SegmentWhitePeppersButton
        function SegmentWhitePeppersButtonPushed(app, event)
            % set lower and upper threshold
            app.lowerThreshold = 0;
            app.upperThreshold = 1;
            app.LowerThresholdSlider.Value = 0;
            app.UpperThresholdSlider.Value = 1;

            % only lower limit on saturation
            app.satLower = 0;
            app.satUpper = 0.3;

            app.brightLower = 0.75;
            app.brightUpper = 1;

            %update colour map and output image
            updateColour(app);
            updateImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputImage = uiaxes(app.UIFigure);
            app.InputImage.Visible = 'off';
            app.OutputImage = uiaxes(app.UIFigure);
            app.OutputImage.Visible = 'off';
            app.ColourDisplay = uiaxes(app.UIFigure);
            app.ColourDisplay.Visible = 'off';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Colour based image segmentation';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Image';
            app.LowerThresholdSliderLabel = uilabel(app.UIFigure);
            app.LowerThresholdSliderLabel.Text = 'Lower Threshold';
            app.LowerThresholdSlider = uislider(app.UIFigure);
            app.LowerThresholdSlider.Limits = [0 1];
            app.LowerThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @LowerThresholdSliderValueChanged, true);
            app.LowerThresholdSlider.Value = 0.1;
            app.UpperThresholdSliderLabel = uilabel(app.UIFigure);
            app.UpperThresholdSliderLabel.Text = 'Upper Threshold';
            app.UpperThresholdSlider = uislider(app.UIFigure);
            app.UpperThresholdSlider.Limits = [0 1];
            app.UpperThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @UpperThresholdSliderValueChanged, true);
            app.UpperThresholdSlider.Value = 0.3;
            app.HueRangeLabel = uilabel(app.UIFigure);
            app.HueRangeLabel.Text = 'Hue Range';
            app.SegmentIrisButton = uibutton(app.UIFigure, 'push');
            app.SegmentIrisButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentIrisButtonPushed, true);
            app.SegmentIrisButton.Text = 'Segment Iris';
            app.SegmentGreenPeppersButton = uibutton(app.UIFigure, 'push');
            app.SegmentGreenPeppersButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentGreenPeppersButtonPushed, true);
            app.SegmentGreenPeppersButton.Text = 'Segment Green Peppers';
            app.SegmentSkinButton = uibutton(app.UIFigure, 'push');
            app.SegmentSkinButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentSkinButtonPushed, true);
            app.SegmentSkinButton.Text = 'Segment Skin';
            app.SegmentRedPeppersButton = uibutton(app.UIFigure, 'push');
            app.SegmentRedPeppersButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentRedPeppersButtonPushed, true);
            app.SegmentRedPeppersButton.Text = 'Segment Red Peppers';
            app.SegmentYellowPeppersButton = uibutton(app.UIFigure, 'push');
            app.SegmentYellowPeppersButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentYellowPeppersButtonPushed, true);
            app.SegmentYellowPeppersButton.Text = 'Segment Yellow Peppers';
            app.SegmentWhitePeppersButton = uibutton(app.UIFigure, 'push');
            app.SegmentWhitePeppersButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentWhitePeppersButtonPushed, true);
            app.SegmentWhitePeppersButton.Text = 'Segment White Peppers';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IsaacBergl_22710992_Lab04

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end